classdef wfregressionExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ListBoxLabel  matlab.ui.control.Label
        sessList      matlab.ui.control.ListBox
        ReloadButton  matlab.ui.control.Button
        visPanel      matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Files % Description
        rootdir % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.Folder = uigetdir('/Volumes/GoogleDrive/Other computers/ImagingDESKTOP-AR620FK/processed');
            app.rootdir = '/Volumes/GoogleDrive/Other computers/ImagingDESKTOP-AR620FK/processed/regression_coefs';
            app.Files = dir(fullfile(app.rootdir, '*regression.mat'));
            app.sessList.Items = {app.Files.name};
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.Files = dir(fullfile(app.rootdir, '*regression.mat'));
            app.sessList.Items = {app.Files.name};
        end

        % Value changed function: sessList
        function sessListValueChanged(app, event)
            name = app.sessList.Value;
            disp(name)
            load(fullfile(app.rootdir, name), 'his_coef_arr');
            
            
            %% visualize the coefficients
            for i = 1:6
                ax = subplot(2,3,i, 'Parent', app.visPanel);
                imagesc(ax, squeeze(his_coef_arr(:,:,i)))
                caxis(ax, [-0.02, 0.02])
%                 caxis([-0.02, 0.02])
                colormap(ax, redblue(256));
                app.visPanel.Title = name;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.sessList = uilistbox(app.UIFigure);
            app.sessList.ValueChangedFcn = createCallbackFcn(app, @sessListValueChanged, true);
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.visPanel = uipanel(app.UIFigure);
            app.visPanel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wfregressionExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end